classdef cmc_isometric < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                  matlab.ui.Figure
		SaveAnalysisButton        matlab.ui.control.Button
		TabGroup                  matlab.ui.container.TabGroup
		DataTab                   matlab.ui.container.Tab
		SaveAnalysisEpochsButton  matlab.ui.control.Button
		UIAxes_tricep             matlab.ui.control.UIAxes
		UIAxes_bicep              matlab.ui.control.UIAxes
		UIAxes_C4                 matlab.ui.control.UIAxes
		UIAxes_C3                 matlab.ui.control.UIAxes
		ResultsTab                matlab.ui.container.Tab
		SaveOutputButton          matlab.ui.control.Button
		ComputeCoherencesButton   matlab.ui.control.Button
		UIAxes_bicep_tricep_coh   matlab.ui.control.UIAxes
		UIAxes_C4_tricep_coh      matlab.ui.control.UIAxes
		UIAxes_C4_bicep_coh       matlab.ui.control.UIAxes
		UIAxes_C3_tricep_coh      matlab.ui.control.UIAxes
		UIAxes_C3_bicep_coh       matlab.ui.control.UIAxes
		UIAxes_tricep_fft         matlab.ui.control.UIAxes
		UIAxes_bicep_fft          matlab.ui.control.UIAxes
		UIAxes_C4_fft             matlab.ui.control.UIAxes
		UIAxes_C3_fft             matlab.ui.control.UIAxes
		FileNameLabel             matlab.ui.control.Label
		EEGfileLabel              matlab.ui.control.Label
		SelectButton              matlab.ui.control.Button
	end


	properties (Access = public)
		time     % time vector of downsampled data
		c3_car   % c3 data common avg ref (downsampled and filtered)
		c4_car   % c4 data common avg ref
		bicep_filt  % bicep emg data
		tricep_filt  % tricep emg data
		samp_rate	 % sampling rate (data is downsampled to)
		seg_pwr		 % num of datapoints for analysis as a power of 2
		c3_bicep	 % neurospec results for c3-bicep coherence
		c4_bicep     %                       c4-bicep
		c3_tricep    %                       c3-tricep
		c4_tricep    %                       c4-tricep
		bicep_tricep %                       bicep-tricep

		analysis_epochs % time intervals of data to analyze

		alpha_band
		beta_band
		low_gamma_band
		high_gamma_band
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			linkaxes([app.UIAxes_C3 app.UIAxes_C4 app.UIAxes_bicep app.UIAxes_tricep], 'x')
			app.samp_rate = 1000;	% sampling rate (data will be downsampled to this)
			app.seg_pwr = 10; % 2^10 = 1024

			app.alpha_band = [6 15]; % ref = Lee2014
			app.beta_band = [16 35]; % ref = Lee2014
			app.low_gamma_band = [36 55]; % ref = Lee2014
			app.high_gamma_band = [66 85]; % ref = Lee2021 (alph = 8–12 Hz, b-band: 13–35 Hz, low gamma: 36–55 Hz)
		end

		% Button pushed function: SelectButton
		function SelectButtonPushed(app, event)
			% request eeg file to process
			disp('Choose a BrainVision .vhdr file or .mat saved analysis file')
			[fname, pname] = uigetfile({'*.*', 'All files (*.*)'; ...
				'*.vhdr', 'BrainVision header file (*.vhdr)'; ...
				'*.mat', 'MATLAB file (*.mat)'}, 'Choose a file');
			if isequal(fname, 0) || isequal(pname,0)
				disp('User pressed cancel')
				return
			else
				filename = fullfile(pname, fname);
				app.FileNameLabel.Text = filename;
			end
			h_dlg = uiprogressdlg(app.UIFigure, 'Title', 'Reading in Data', 'Indeterminate','on');

			[pname, fname, ext] = fileparts(filename);
			switch ext
				case '.vhdr'
					% read in the data

					[data, ~] = pop_loadbv(pname, [fname ext]);
					n_chans = 31;
					% find the chan numbers for C3, C4, and Cz, bicep, and tricep
					c3_chan_num = []; c4_chan_num = []; cz_chan_num = []; bicep_chan_num = []; tricep_chan_num = [];
					for ch_cnt = 1:length(data.chanlocs)
						switch lower(data.chanlocs(ch_cnt).labels)
							case 'c3'
								c3_chan_num = ch_cnt;
							case 'c4'
								c4_chan_num = ch_cnt;
							case 'cz'
								cz_chan_num = ch_cnt;
							case 'bicep'
								bicep_chan_num = ch_cnt;
							case 'tricep'
								tricep_chan_num = ch_cnt;
						end
					end
					if isempty(c3_chan_num)
						keyboard
					end
					if isempty(c4_chan_num)
						keyboard
					end
					if isempty(cz_chan_num)
						keyboard
					end
					if isempty(bicep_chan_num)
						keyboard
					end
					if isempty(tricep_chan_num)
						keyboard
					end

					% downsample to 1kHz
					h_dlg.Title = 'Down sampling the Data';

					data.srate_down = app.samp_rate;
					factor = data.srate / data.srate_down;
					if abs(factor-fix(factor)) > eps
						fprintf('cmc_isometric.m sampling rate, %g, cannot be downsampled to 1kHz\n', data.srate);
						return
					end


					fprintf('down sampling by a factor of %d\n', factor)
					data.downsamp = zeros(size(data.data,1), data.pnts/factor);
					for c_cnt = [1:n_chans, bicep_chan_num, tricep_chan_num]
						if factor > 1
							data.downsamp(c_cnt, :) = decimate(double(data.data(c_cnt,:)), factor);
						else
							data.downsamp(c_cnt, :) = double(data.data(c_cnt, :));
						end
					end


					app.time = (1:length(data.downsamp)) / data.srate_down;

					h_dlg.Title = 'Filtering the Data';
					% filter values
					Setup.EMGcutOffLowHz=5;
					Setup.EMGcutOffHighHz=499;
					Setup.EEGcutOffLowHz=0.5;
					Setup.EEGcutOffHighHz=100;

					% 			low = Setup.EMGcutOffLowHz / (data.srate/2);
					% 			high = Setup.EMGcutOffHighHz / (data.srate/2);
					% 			[B_emg, A_emg] = butter(5, [low, high], 'bandpass' );
					%
					% 			low = Setup.EEGcutOffLowHz / (data.srate/2);
					% 			high = Setup.EEGcutOffHighHz / (data.srate/2);
					% 			[B_eeg, A_eeg] = butter(4, [low, high], 'bandpass' );

					% filter the data

					eeg_hp = zeros(n_chans, data.pnts/factor);
					eeg_filt = zeros(n_chans, data.pnts/factor);
					for c_cnt = 1:n_chans
						eeg_hp(c_cnt, :) = hpfilt(data.downsamp(c_cnt,:)', 4, Setup.EEGcutOffLowHz, data.srate_down)';
						eeg_filt(c_cnt, :) = lpfilt(eeg_hp(c_cnt, :), 4, Setup.EEGcutOffHighHz, data.srate_down)';
					end

					% 			c3_hp = hpfilt(data.downsamp(c3_chan_num,:)', 4, Setup.EEGcutOffLowHz, data.srate_down);
					% 			c3_filt = lpfilt(c3_hp, 4, Setup.EEGcutOffHighHz, data.srate_down);
					%
					% 			c4_hp = hpfilt(data.downsamp(c4_chan_num,:)', 4, Setup.EEGcutOffLowHz, data.srate_down);
					% 			c4_filt = lpfilt(c4_hp, 4, Setup.EEGcutOffHighHz, data.srate_down);
					%
					% 			cz_hp = hpfilt(data.downsamp(cz_chan_num,:)', 4, Setup.EEGcutOffLowHz, data.srate_down);
					% 			cz_filt = lpfilt(cz_hp, 4, Setup.EEGcutOffHighHz, data.srate_down);

					% common average reference
					F_all = ones(n_chans)*(-1/(n_chans-1)) + eye(n_chans)*(1+1/(n_chans-1)); %CAR matrix
					app.c3_car = F_all(c3_chan_num, :) * eeg_filt;
					app.c4_car = F_all(c4_chan_num, :) * eeg_filt;

					% 			c3_cz = c3_filt - cz_filt;
					% 			c4_cz = c4_filt - cz_filt;

					bicep_hp = hpfilt(data.downsamp(bicep_chan_num,:)', 4, Setup.EMGcutOffLowHz, data.srate_down);
					app.bicep_filt = lpfilt(bicep_hp, 4, Setup.EMGcutOffHighHz, data.srate_down);

					tricep_hp = hpfilt(data.downsamp(tricep_chan_num,:)', 4, Setup.EMGcutOffLowHz, data.srate_down);
					app.tricep_filt = lpfilt(tricep_hp, 4, Setup.EMGcutOffHighHz, data.srate_down);

					% display the data
					h_dlg.Title = 'Drawing the Data';
					draw_data(app)
% 					if ~isempty(app.UIAxes_C3.Children)
% 						delete(app.UIAxes_C3.Children)
% 					end
% 					line(app.UIAxes_C3, app.time, app.c3_car)
% 					if ~isempty(app.UIAxes_C4.Children)
% 						delete(app.UIAxes_C4.Children)
% 					end
% 					line(app.UIAxes_C4, app.time, app.c4_car)
% 					if ~isempty(app.UIAxes_bicep.Children)
% 						delete(app.UIAxes_bicep.Children)
% 					end
% 					line(app.UIAxes_bicep, app.time, app.bicep_filt)
% 					if ~isempty(app.UIAxes_tricep.Children)
% 						delete(app.UIAxes_tricep.Children)
% 					end
% 					line(app.UIAxes_tricep, app.time, app.tricep_filt)
% 					app.UIAxes_C3.XLim = [0 max(app.time)];

					% get saved analysis epochs, if there are any
					get_analysis_epochs(app)

					% add event times to emg and patches to all axes
					for e_cnt = 1:length(data.event)
						if strcmpi(data.event(e_cnt).code, 'rest')
							t = data.event(e_cnt).latency / data.srate;
							line(app.UIAxes_bicep, [t t], app.UIAxes_bicep.YLim, 'Color', [0.9 0 0])
							line(app.UIAxes_tricep, [t t], app.UIAxes_tricep.YLim, 'Color', [0.9 0 0])
						elseif strcmpi(data.event(e_cnt).code, 'extend')
							t = data.event(e_cnt).latency / data.srate;
							line(app.UIAxes_bicep, [t t], app.UIAxes_bicep.YLim, 'Color', [0 0.9 0])
							line(app.UIAxes_tricep, [t t], app.UIAxes_tricep.YLim, 'Color', [0 0.9 0])
							if isempty(app.analysis_epochs)
								add_cmc_patch(app, t+2, t+6)
							end
						end
					end
				case '.mat'
					save_struct = load(filename);
					app.time = save_struct.time;
					app.c3_car = save_struct.c3_car;
					app.c4_car = save_struct.c4_car;
					app.bicep_filt = save_struct.bicep_filt;
					app.tricep_filt = save_struct.tricep_filt;
					app.samp_rate = save_struct.samp_rate;
					app.seg_pwr = save_struct.seg_pwr;
					app.c3_bicep = save_struct.c3_bicep;
					app.c4_bicep = save_struct.c4_bicep;
					app.c3_tricep = save_struct.c3_tricep;
					app.c4_tricep = save_struct.c4_tricep;
					app.bicep_tricep = save_struct.bicep_tricep;
					app.analysis_epochs = save_struct.analysis_epochs;
					app.alpha_band = save_struct.alpha_band;
					app.beta_band = save_struct.beta_band;
					app.low_gamma_band = save_struct.low_gamma_band;
					app.high_gamma_band = save_struct.high_gamma_band;
					% display the data
					h_dlg.Title = 'Drawing the Data';
					draw_data(app)
					% if there are computed results, display them
					if ~isempty(app.c3_bicep.f)
						draw_results(app)
					end
				otherwise
					beep
					disp('invalid file type chosen')
			end

			if ~isempty(app.analysis_epochs)
				for e_cnt = 1:length(app.analysis_epochs.t_start)
					add_cmc_patch(app, app.analysis_epochs.t_start(e_cnt), app.analysis_epochs.t_end(e_cnt))
				end
			end

			close(h_dlg)
		end

		% Button pushed function: ComputeCoherencesButton
		function ComputeCoherencesButtonPushed(app, event)
			% get the time intervals to analyze
			patch_list = findobj(app.UIAxes_tricep, 'type', 'Patch');
			trig_times = zeros(length(patch_list), 1);
			duration = zeros(length(patch_list), 1);

			for p_cnt = 1:length(patch_list)
				xdata = patch_list(p_cnt).XData;
				t_start = xdata(1);
				t_end = xdata(3);
				ind_start = find(app.time >= t_start, 1);
				ind_end = find(app.time >= t_end, 1);

				trig_times(p_cnt) = ind_start;
				duration(p_cnt) = ind_end - ind_start + 1;
			end

			% call the neurospec function
			[app.c3_bicep.f, app.c3_bicep.t, app.c3_bicep.cl, app.c3_bicep.sc] = ...
				sp2a2_m1(1, app.c3_car', app.bicep_filt, trig_times, duration, app.samp_rate, app.seg_pwr, '');
			[app.c4_bicep.f, app.c4_bicep.t, app.c4_bicep.cl, app.c4_bicep.sc] = ...
				sp2a2_m1(1, app.c4_car', app.bicep_filt, trig_times, duration, app.samp_rate, app.seg_pwr, '');
			[app.c3_tricep.f, app.c3_tricep.t, app.c3_tricep.cl, app.c3_tricep.sc] = ...
				sp2a2_m1(1, app.c3_car', app.tricep_filt, trig_times, duration, app.samp_rate, app.seg_pwr, '');
			[app.c4_tricep.f, app.c4_tricep.t, app.c4_tricep.cl, app.c4_tricep.sc] = ...
				sp2a2_m1(1, app.c4_car', app.tricep_filt, trig_times, duration, app.samp_rate, app.seg_pwr, '');
			[app.bicep_tricep.f, app.bicep_tricep.t, app.bicep_tricep.cl, app.bicep_tricep.sc] = ...
				sp2a2_m1(1, app.bicep_filt, app.tricep_filt, trig_times, duration, app.samp_rate, app.seg_pwr, '');
			% Output parameters
			%  f column 1       frequency in Hz.
			%  f column 2       Log input/dat1  spectrum.
			%  f column 3       Log output/dat2 spectrum.
			%  f column 4       Coherence.
			%  f column 5       Phase.
			%  f column 6       Log of gain magnitude (with s option).
			%  f column 6 or 7  Periodogram COV test  input channel (with c option).
			%  f column 7 or 8  Periodogram COV test output channel (with c option).
			%
			%  t column 1       Lag in ms.
			%  t column 2       Cumulant density.
			%  t column 3       Impulse response (with s option).
			%
			%  cl.type          Analysis type (0, 1, 2)
			%  cl.seg_size      Segment length.
			%  cl.seg_tot       Number of segments.
			%  cl.seg_tot_var   Effective no of segments, used to calculate confidence limits.
			%  cl.samp_tot      Number of samples analysed.
			%  cl.samp_rate     Sampling rate of data (samps/sec).
			%  cl.dt            Time domain bin width (ms).
			%  cl.df            Frequency domain bin width (Hz).
			%  cl.f_c95         95% confidence limit for Log spectral estimates.
			%  cl.ch_c95        95% confidence limit for coherence.
			%  cl.q_c95         95% confidence limit for cumulant density.
			%  cl.a_c95         95% confidence limit for impulse response      (with s option).
			%  cl.col_g         column in f matrix containing gain estimate    (with s option).
			%  cl.col_a         column in t matrix containing impulse response (with s option).
			%  col_cova         Column containing Periodogram COV test on  input channel (with c option).
			%  col_covb         Column containing Periodogram COV test on output channel (with c option).
			%  cl.seg_pts       Data points in segment       (Type 2 only).
			%  cl.offset        Offset value(s) for analysis (Type 2 only).
			%  cl.opt_str       Copy of options string.
			%  cl.what          Text label, used in plotting routines.
			%
			%  sc column 1      f11.
			%  sc column 2      f22.
			%  sc column 3      f21 (Complex).


			% display output
			% 			freq = app.samp_rate/2;
			% 			lag_tot = freq;
			% 			lag_neg = freq/2;
			% 			ch_max = 2 * max(app.c3_bicep.f(:,4));
			% 			label = 'C3-bicep';
			app.c3_bicep.cl.what = 'C3-bicep';
			% 			figure
			% 			psp2(app.c3_bicep.f, app.c3_bicep.t, app.c3_bicep.cl, freq, lag_tot, lag_neg, ch_max)

			draw_results(app)

% 			% ffts
% 			line(app.UIAxes_C3_fft, app.c3_bicep.f(:,1), app.c3_bicep.f(:,2)); % f col 2  = c3fft
% 			line(app.UIAxes_bicep_fft, app.c3_bicep.f(:,1), app.c3_bicep.f(:,3)); % f col 3 = bicep fft
% 			line(app.UIAxes_C4_fft, app.c4_bicep.f(:,1), app.c4_bicep.f(:,2)); % f col 2  = c4 fft
% 			line(app.UIAxes_tricep_fft, app.c3_tricep.f(:,1), app.c3_tricep.f(:,3)); % f col 3 = tricep fft
% 
% 			% coherences
% 			line(app.UIAxes_C3_bicep_coh, app.c3_bicep.f(:,1), app.c3_bicep.f(:,4));
% 			line(app.UIAxes_C3_bicep_coh, [0 100], [app.c3_bicep.cl.ch_c95 app.c3_bicep.cl.ch_c95], ...
% 				'LineStyle', '--', 'Color', [0.9 0 0]);
% 
% 			line(app.UIAxes_C4_bicep_coh, app.c4_bicep.f(:,1), app.c4_bicep.f(:,4));
% 			line(app.UIAxes_C4_bicep_coh, [0 100], [app.c4_bicep.cl.ch_c95 app.c4_bicep.cl.ch_c95], ...
% 				'LineStyle', '--', 'Color', [0.9 0 0]);
% 
% 			line(app.UIAxes_C3_tricep_coh, app.c3_tricep.f(:,1), app.c3_tricep.f(:,4));
% 			line(app.UIAxes_C3_tricep_coh, [0 100], [app.c3_tricep.cl.ch_c95 app.c3_tricep.cl.ch_c95], ...
% 				'LineStyle', '--', 'Color', [0.9 0 0]);
% 
% 			line(app.UIAxes_C4_tricep_coh, app.c4_tricep.f(:,1), app.c4_tricep.f(:,4));
% 			line(app.UIAxes_C4_tricep_coh, [0 100], [app.c4_tricep.cl.ch_c95 app.c4_tricep.cl.ch_c95], ...
% 				'LineStyle', '--', 'Color', [0.9 0 0]);
% 
% 			line(app.UIAxes_bicep_tricep_coh, app.bicep_tricep.f(:,1), app.bicep_tricep.f(:,4));
% 			line(app.UIAxes_bicep_tricep_coh, [0 100], [app.bicep_tricep.cl.ch_c95 app.bicep_tricep.cl.ch_c95], ...
% 				'LineStyle', '--', 'Color', [0.9 0 0]);
% 
% 			% show freq bands
% 			ax_list = [app.UIAxes_C3_bicep_coh, app.UIAxes_C4_bicep_coh, ...
% 				app.UIAxes_C3_tricep_coh, app.UIAxes_C4_tricep_coh, ...
% 				app.UIAxes_bicep_tricep_coh];
% 			for a_cnt = 1:length(ax_list)
% 				ylim = ax_list(a_cnt).YLim;
% 				patch(ax_list(a_cnt), 'XData', [app.alpha_band(1), app.alpha_band(1), app.alpha_band(2), app.alpha_band(2)], ...
% 					'YData', [ylim(1) ylim(2) ylim(2) ylim(1)], ...
% 					'FaceColor', [235, 237, 49]/255, 'EdgeColor', [0.5 0.5 0.5], 'FaceAlpha', 0.3);
% 				patch(ax_list(a_cnt), 'XData', [app.beta_band(1), app.beta_band(1), app.beta_band(2), app.beta_band(2)], ...
% 					'YData', [ylim(1) ylim(2) ylim(2) ylim(1)], ...
% 					'FaceColor', [63, 221, 194]/255, 'EdgeColor', [0.5 0.5 0.5], 'FaceAlpha', 0.3);
% 				patch(ax_list(a_cnt), 'XData', [app.low_gamma_band(1), app.low_gamma_band(1), app.low_gamma_band(2), app.low_gamma_band(2)], ...
% 					'YData', [ylim(1) ylim(2) ylim(2) ylim(1)], ...
% 					'FaceColor', [180, 131, 253]/255, 'EdgeColor', [0.5 0.5 0.5], 'FaceAlpha', 0.3);
% 				patch(ax_list(a_cnt), 'XData', [app.high_gamma_band(1), app.high_gamma_band(1), app.high_gamma_band(2), app.high_gamma_band(2)], ...
% 					'YData', [ylim(1) ylim(2) ylim(2) ylim(1)], ...
% 					'FaceColor', [203, 152, 178]/255, 'EdgeColor', [0.5 0.5 0.5], 'FaceAlpha', 0.3);
% 			end
		end

		% Button pushed function: SaveAnalysisEpochsButton
		function SaveAnalysisEpochsButtonPushed(app, event)
			% get the time intervals to analyze
			patch_list = findobj(app.UIAxes_tricep, 'type', 'Patch');
			app.analysis_epochs.t_start = zeros(length(patch_list), 1);
			app.analysis_epochs.t_end = zeros(length(patch_list), 1);

			for p_cnt = 1:length(patch_list)
				xdata = patch_list(p_cnt).XData;
				app.analysis_epochs.t_start(p_cnt) = xdata(1);
				app.analysis_epochs.t_end(p_cnt) = xdata(3);
			end
			save_loc = get_save_path(app, true);
			[~,fname,~] = fileparts(app.FileNameLabel.Text);
			save_file = fullfile(save_loc, [fname '_epochs.txt']);
			if exist(save_file, 'file') == 2
				selected_option = uiconfirm(app.UIFigure, [save_file ' already exists. Do you want to overwrite it?'], ...
					'Confirm Save', 'Options', {'Yes','No'});
				if contains(selected_option, 'Yes')
					% write the file
					write_info(save_file, app.analysis_epochs)
				end
			else
				% write the file
				write_info(save_file, app.analysis_epochs)
			end


		end

		% Button pushed function: SaveOutputButton
		function SaveOutputButtonPushed(app, event)
			% compute z-transform of coherences
			r = app.c3_bicep.f(:,4);
			app.c3_bicep.z = 0.5 * log10((1+r) ./ (1-r));
			r = app.c4_bicep.f(:,4);
			app.c4_bicep.z = 0.5 * log10((1+r) ./ (1-r));
			r = app.c3_tricep.f(:,4);
			app.c3_tricep.z = 0.5 * log10((1+r) ./ (1-r));
			r = app.c4_tricep.f(:,4);
			app.c4_tricep.z = 0.5 * log10((1+r) ./ (1-r));
			r = app.bicep_tricep.f(:,4);
			app.bicep_tricep.z = 0.5 * log10((1+r) ./ (1-r));

			% compute the integral of the z-xfm data in each band
			delta_f = mean(diff(app.c3_bicep.f(:,1)));
			alpha_msk = app.c3_bicep.f(:,1) >= app.alpha_band(1) & ...
				app.c3_bicep.f(:,1) <= app.alpha_band(2);
			beta_msk = app.c3_bicep.f(:,1) >= app.beta_band(1) & ...
				app.c3_bicep.f(:,1) <= app.beta_band(2);
			low_gamma_msk = app.c3_bicep.f(:,1) >= app.low_gamma_band(1) & ...
				app.c3_bicep.f(:,1) <= app.low_gamma_band(2);
			high_gamma_msk = app.c3_bicep.f(:,1) >= app.high_gamma_band(1) & ...
				app.c3_bicep.f(:,1) <= app.high_gamma_band(2);
			
			out.c3_bicep_alpha_area = delta_f * trapz(app.c3_bicep.z(alpha_msk));
			out.c3_bicep_beta_area = delta_f * trapz(app.c3_bicep.z(beta_msk));
			out.c3_bicep_low_gamma_area = delta_f * trapz(app.c3_bicep.z(low_gamma_msk));
			out.c3_bicep_high_gamma_area = delta_f * trapz(app.c3_bicep.z(high_gamma_msk));

			out.c4_bicep_alpha_area = delta_f * trapz(app.c4_bicep.z(alpha_msk));
			out.c4_bicep_beta_area = delta_f * trapz(app.c4_bicep.z(beta_msk));
			out.c4_bicep_low_gamma_area = delta_f * trapz(app.c4_bicep.z(low_gamma_msk));
			out.c4_bicep_high_gamma_area = delta_f * trapz(app.c4_bicep.z(high_gamma_msk));

			out.c3_tricep_alpha_area = delta_f * trapz(app.c3_tricep.z(alpha_msk));
			out.c3_tricep_beta_area = delta_f * trapz(app.c3_tricep.z(beta_msk));
			out.c3_tricep_low_gamma_area = delta_f * trapz(app.c3_tricep.z(low_gamma_msk));
			out.c3_tricep_high_gamma_area = delta_f * trapz(app.c3_tricep.z(high_gamma_msk));

			out.c4_tricep_alpha_area = delta_f * trapz(app.c4_tricep.z(alpha_msk));
			out.c4_tricep_beta_area = delta_f * trapz(app.c4_tricep.z(beta_msk));
			out.c4_tricep_low_gamma_area = delta_f * trapz(app.c4_tricep.z(low_gamma_msk));
			out.c4_tricep_high_gamma_area = delta_f * trapz(app.c4_tricep.z(high_gamma_msk));

			out.bicep_tricep_alpha_area = delta_f * trapz(app.bicep_tricep.z(alpha_msk));
			out.bicep_tricep_beta_area = delta_f * trapz(app.bicep_tricep.z(beta_msk));
			out.bicep_tricep_low_gamma_area = delta_f * trapz(app.bicep_tricep.z(low_gamma_msk));
			out.bicep_tricep_high_gamma_area = delta_f * trapz(app.bicep_tricep.z(high_gamma_msk));

			% save the output
			save_loc = get_save_path(app, true);
			[~,fname,~] = fileparts(app.FileNameLabel.Text);
			save_file = fullfile(save_loc, [fname '_coherence.txt']);
			if exist(save_file, 'file') == 2
				selected_option = uiconfirm(app.UIFigure, [save_file ' already exists. Do you want to overwrite it?'], ...
					'Confirm Save', 'Options', {'Yes','No'});
				if contains(selected_option, 'Yes')
					% write the file
					write_info(save_file, out)
				end
			else
				% write the file
				write_info(save_file, out)
			end
		end

		% Button pushed function: SaveAnalysisButton
		function SaveAnalysisButtonPushed(app, event)
			% updata analysis epochs
			patch_list = findobj(app.UIAxes_tricep, 'type', 'Patch');
			app.analysis_epochs.t_start = zeros(length(patch_list), 1);
			app.analysis_epochs.t_end = zeros(length(patch_list), 1);

			for p_cnt = 1:length(patch_list)
				xdata = patch_list(p_cnt).XData;
				app.analysis_epochs.t_start(p_cnt) = xdata(1);
				app.analysis_epochs.t_end(p_cnt) = xdata(3);
			end
			
			save_loc = get_save_path(app, true);
			[~,fname,~] = fileparts(app.FileNameLabel.Text);
			save_file = fullfile(save_loc, [fname '.mat']);
			if exist(save_file, 'file') == 2
				selected_option = uiconfirm(app.UIFigure, [save_file ' already exists. Do you want to overwrite it?'], ...
					'Confirm Save', 'Options', {'Yes','No'});
				if contains(selected_option, 'Yes')
					% save the app properties in a mat file
					save_struct.time = app.time;
					save_struct.c3_car   	= app.c3_car;
					save_struct.c4_car   	= app.c4_car;
					save_struct.bicep_filt  	= app.bicep_filt;
					save_struct.tricep_filt 	= app.tricep_filt;
					save_struct.samp_rate		= app.samp_rate;
					save_struct.seg_pwr			= app.seg_pwr;
					save_struct.c3_bicep		= app.c3_bicep;
					save_struct.c4_bicep    	= app.c4_bicep;
					save_struct.c3_tricep   	= app.c3_tricep;
					save_struct.c4_tricep   	= app.c4_tricep;
					save_struct.bicep_tricep	= app.bicep_tricep;

					save_struct.analysis_epochs	= app.analysis_epochs;
					save_struct.alpha_band	= app.alpha_band;
					save_struct.beta_band	= app.beta_band;
					save_struct.low_gamma_band	= app.low_gamma_band;
					save_struct.high_gamma_band	= app.high_gamma_band;
					save(save_file, '-struct', 'save_struct')
				end
			else
				% save the app properties in a mat file
				save_struct.time = app.time;
				save_struct.c3_car   	= app.c3_car;
				save_struct.c4_car   	= app.c4_car;
				save_struct.bicep_filt  	= app.bicep_filt;
				save_struct.tricep_filt 	= app.tricep_filt;
				save_struct.samp_rate		= app.samp_rate;
				save_struct.seg_pwr			= app.seg_pwr;
				save_struct.c3_bicep		= app.c3_bicep;
				save_struct.c4_bicep    	= app.c4_bicep;
				save_struct.c3_tricep   	= app.c3_tricep;
				save_struct.c4_tricep   	= app.c4_tricep;
				save_struct.bicep_tricep	= app.bicep_tricep;

				save_struct.analysis_epochs	= app.analysis_epochs;
				save_struct.alpha_band	= app.alpha_band;
				save_struct.beta_band	= app.beta_band;
				save_struct.low_gamma_band	= app.low_gamma_band;
				save_struct.high_gamma_band	= app.high_gamma_band;
				save(save_file, '-struct', 'save_struct')
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.SelectButton = uibutton(app.UIFigure, 'push');
			app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
			app.SelectButton.Text = 'Select';
			app.EEGfileLabel = uilabel(app.UIFigure);
			app.EEGfileLabel.Text = 'EEG file:';
			app.FileNameLabel = uilabel(app.UIFigure);
			app.TabGroup = uitabgroup(app.UIFigure);
			app.DataTab = uitab(app.TabGroup);
			app.DataTab.Title = 'Data';
			app.UIAxes_C3 = uiaxes(app.DataTab);
			title(app.UIAxes_C3, 'EEG')
			xlabel(app.UIAxes_C3, ' ')
			ylabel(app.UIAxes_C3, 'C3')
			zlabel(app.UIAxes_C3, 'Z')
			app.UIAxes_C4 = uiaxes(app.DataTab);
			xlabel(app.UIAxes_C4, ' ')
			ylabel(app.UIAxes_C4, 'C4')
			zlabel(app.UIAxes_C4, 'Z')
			app.UIAxes_bicep = uiaxes(app.DataTab);
			title(app.UIAxes_bicep, 'EMG')
			xlabel(app.UIAxes_bicep, ' ')
			ylabel(app.UIAxes_bicep, 'Bicep')
			zlabel(app.UIAxes_bicep, 'Z')
			app.UIAxes_tricep = uiaxes(app.DataTab);
			xlabel(app.UIAxes_tricep, 'Time (s)')
			ylabel(app.UIAxes_tricep, 'Tricep')
			zlabel(app.UIAxes_tricep, 'Z')
			app.SaveAnalysisEpochsButton = uibutton(app.DataTab, 'push');
			app.SaveAnalysisEpochsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisEpochsButtonPushed, true);
			app.SaveAnalysisEpochsButton.Text = 'Save Analysis Epochs';
			app.ResultsTab = uitab(app.TabGroup);
			app.ResultsTab.Title = 'Results';
			app.UIAxes_C3_fft = uiaxes(app.ResultsTab);
			title(app.UIAxes_C3_fft, 'C3')
			xlabel(app.UIAxes_C3_fft, 'Freq (Hz)')
			ylabel(app.UIAxes_C3_fft, 'Power')
			zlabel(app.UIAxes_C3_fft, 'Z')
			app.UIAxes_C3_fft.XLim = [0 85];
			app.UIAxes_C4_fft = uiaxes(app.ResultsTab);
			title(app.UIAxes_C4_fft, 'C4')
			xlabel(app.UIAxes_C4_fft, 'Freq (Hz)')
			ylabel(app.UIAxes_C4_fft, 'Power')
			zlabel(app.UIAxes_C4_fft, 'Z')
			app.UIAxes_C4_fft.XLim = [0 85];
			app.UIAxes_bicep_fft = uiaxes(app.ResultsTab);
			title(app.UIAxes_bicep_fft, 'Bicep')
			xlabel(app.UIAxes_bicep_fft, 'Freq (Hz)')
			ylabel(app.UIAxes_bicep_fft, 'Power')
			zlabel(app.UIAxes_bicep_fft, 'Z')
			app.UIAxes_bicep_fft.XLim = [0 85];
			app.UIAxes_tricep_fft = uiaxes(app.ResultsTab);
			title(app.UIAxes_tricep_fft, 'Tricep')
			xlabel(app.UIAxes_tricep_fft, 'Freq (Hz)')
			ylabel(app.UIAxes_tricep_fft, 'Power')
			zlabel(app.UIAxes_tricep_fft, 'Z')
			app.UIAxes_tricep_fft.XLim = [0 85];
			app.UIAxes_C3_bicep_coh = uiaxes(app.ResultsTab);
			title(app.UIAxes_C3_bicep_coh, 'C3-Bicep')
			xlabel(app.UIAxes_C3_bicep_coh, 'Freq (Hz)')
			ylabel(app.UIAxes_C3_bicep_coh, 'Coherence')
			zlabel(app.UIAxes_C3_bicep_coh, 'Z')
			app.UIAxes_C3_bicep_coh.XLim = [0 85];
			app.UIAxes_C3_tricep_coh = uiaxes(app.ResultsTab);
			title(app.UIAxes_C3_tricep_coh, 'C3-Tricep')
			xlabel(app.UIAxes_C3_tricep_coh, 'Freq (Hz)')
			ylabel(app.UIAxes_C3_tricep_coh, 'Coherence')
			zlabel(app.UIAxes_C3_tricep_coh, 'Z')
			app.UIAxes_C3_tricep_coh.XLim = [0 85];
			app.UIAxes_C4_bicep_coh = uiaxes(app.ResultsTab);
			title(app.UIAxes_C4_bicep_coh, 'C4-Bicep')
			xlabel(app.UIAxes_C4_bicep_coh, 'Freq (Hz)')
			ylabel(app.UIAxes_C4_bicep_coh, 'Coherence')
			zlabel(app.UIAxes_C4_bicep_coh, 'Z')
			app.UIAxes_C4_bicep_coh.XLim = [0 85];
			app.UIAxes_C4_tricep_coh = uiaxes(app.ResultsTab);
			title(app.UIAxes_C4_tricep_coh, 'C4-Tricep')
			xlabel(app.UIAxes_C4_tricep_coh, 'Freq (Hz)')
			ylabel(app.UIAxes_C4_tricep_coh, 'Coherence')
			zlabel(app.UIAxes_C4_tricep_coh, 'Z')
			app.UIAxes_C4_tricep_coh.XLim = [0 85];
			app.UIAxes_bicep_tricep_coh = uiaxes(app.ResultsTab);
			title(app.UIAxes_bicep_tricep_coh, 'Bicep-Tricep')
			xlabel(app.UIAxes_bicep_tricep_coh, 'Freq (Hz)')
			ylabel(app.UIAxes_bicep_tricep_coh, 'Coherence')
			zlabel(app.UIAxes_bicep_tricep_coh, 'Z')
			app.UIAxes_bicep_tricep_coh.XLim = [0 85];
			app.ComputeCoherencesButton = uibutton(app.ResultsTab, 'push');
			app.ComputeCoherencesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeCoherencesButtonPushed, true);
			app.ComputeCoherencesButton.Text = 'Compute Coherences';
			app.SaveOutputButton = uibutton(app.ResultsTab, 'push');
			app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
			app.SaveOutputButton.Text = 'Save Output';
			app.SaveAnalysisButton = uibutton(app.UIFigure, 'push');
			app.SaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
			app.SaveAnalysisButton.Text = 'Save Analysis';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = cmc_isometric

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.UIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.UIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end