classdef cocontraction < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CoConUIFigure                  matlab.ui.Figure
		apptobaseButton                matlab.ui.control.Button
		ExperimentButtonGroup          matlab.ui.container.ButtonGroup
		FingerExtendButton             matlab.ui.control.RadioButton
		IsometricTricepButton          matlab.ui.control.RadioButton
		IsometricBicepButton           matlab.ui.control.RadioButton
		BendExtendButton               matlab.ui.control.RadioButton
		ExtendOnlyButton               matlab.ui.control.RadioButton
		BendOnlyButton                 matlab.ui.control.RadioButton
		ExportCocontractionDataButton  matlab.ui.control.Button
		ViconDataFileLabel             matlab.ui.control.Label
		ViconFileNameLabel             matlab.ui.control.Label
		ViconSelectButton              matlab.ui.control.Button
		AnalysisdateEditField          matlab.ui.control.EditField
		AnalysisdateEditFieldLabel     matlab.ui.control.Label
		AnalysisbyEditField            matlab.ui.control.EditField
		AnalysisbyEditFieldLabel       matlab.ui.control.Label
		cocontractionmlappLabel        matlab.ui.control.Label
		EMGDataFileLabel               matlab.ui.control.Label
		EditField_2                    matlab.ui.control.NumericEditField
		EditField                      matlab.ui.control.NumericEditField
		FileNameLabel                  matlab.ui.control.Label
		EMGSelectButton                matlab.ui.control.Button
		UIAxes_elbow_velocity          matlab.ui.control.UIAxes
		UIAxes_elbow_angle             matlab.ui.control.UIAxes
		UIAxes_cci                     matlab.ui.control.UIAxes
		UIAxes_tricep                  matlab.ui.control.UIAxes
		UIAxes_bicep                   matlab.ui.control.UIAxes
		AddEventContextMenu            matlab.ui.container.ContextMenu
		AddMovementEndEventMenu        matlab.ui.container.Menu
		AddIsometricContextMenu        matlab.ui.container.ContextMenu
		AddIsometricPatchMenu          matlab.ui.container.Menu
	end


	properties (Access = public)
		emg_data      % emg data read in from brainvision file
		samp_rate		% sampling rate to down-sample to
		mvcs			% struct with mvc values
		resting_emg		% struct with resting emg values
		% 		cci_data        % co-contraction - continuous data computed from eeg_data and mvcs
		links			% struct to hold link property handles

		vicon_data		% vicon model output data from csv file
		right_click_loc % mouse location of right-click in elbow angle axes

		coactivation_patches  % struct to hold list of bicep & tricep patches
		cocontraction_data	  % cocontraction info extracted from the coactivation patches
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			app.cocontractionmlappLabel.Text = 'cocontraction.mlapp version 0.5 2023-05-31';
			% link the XLim of the axes
			app.links.axes_xlim = linkprop([app.UIAxes_bicep, app.UIAxes_tricep, app.UIAxes_cci, ...
				app.UIAxes_elbow_angle, app.UIAxes_elbow_velocity], 'XLim');
			app.coactivation_patches = struct();
			app.resting_emg = struct();
			app.samp_rate = 1000; % down sample to 1 kHz
		end

		% Button pushed function: EMGSelectButton
		function SelectButtonPushed(app, event)
			[filename, pathname] = uigetfile('*.vhdr', 'Choose vhdr file with EMG data');
			if ~isequal(filename,0) &&  ~isequal(pathname,0)
				app.FileNameLabel.Text = fullfile(pathname, filename);
				% read in the data
				if contains(filename, '.vhdr')
					% show window that something is going on
					processing_fig_h = uiprogressdlg(app.CoConUIFigure, 'Title', 'Loading EMG Data', 'Indeterminate','on');

					% depending upon the date of the file, define the 2 or 4 emg channels
					% files created after 2022-11-08 have 4 muscles instead of 2
					file_info = dir(app.FileNameLabel.Text);
					if file_info.datenum > datenum(2022,11,08) %#ok<DATNM>
						emg_chans = 64:67;
					else
						emg_chans = [64 65];
					end
					% read in data from brain vision file
					try
						app.emg_data = pop_loadbv(pathname, filename, [], emg_chans);
					catch ME
						if strcmpi(ME.message, 'chans out of available channel range' )
							% try again with fewer channels
							if length(emg_chans) == 4
								emg_chans = [64 65];
							end
							try
								app.emg_data = pop_loadbv(pathname, filename, [], emg_chans);
							catch ME2
								if strcmpi(ME2.message, 'chans out of available channel range' )
									disp('Tried to reduce from 4 to 2 EMG channels. Still not reading in data from ')
									disp(fullfile(pathname, filename))
								else
									rethrow(ME2)
								end
							end
						else
							rethrow(ME)
						end
					end
					% data structure:
					%   comments: 'Original file: 20120517_inv_wrist_flexors_active_rc_epochs_450ms.seg' (or .eeg)
					%       srate: 5000				sampling rate in Hz
					%      nbchan: 8				number of channels recorded
					%    chanlocs: [1x8 struct]		array of the channel names (strings)
					%        pnts: 2250				number of data points in a trial
					%        data: [8x90000 single]	data matrix (1 row for each data collection channel)
					%      trials: 40				number of trials
					%        xmin: -0.0500
					%        xmax: 17.9998
					%       event: [1x80 struct]	struct array with fields:
					%									latency			starting data point number for the event
					%									type			'R128' events are the start of trials we want
					%									code
					%									duration
					%									urevent
					%									epoch
					%     urevent: [1x120 struct]
					%         ref: 'common'

					% verify that the 2 data channels are bicep and tricep
					if contains(app.emg_data.chanlocs(1).labels, 'bicep')
						% correct label
					elseif contains(app.emg_data.chanlocs(1).labels, 'Biceps')
						% incorrect label, change it
						app.emg_data.chanlocs(1).labels = 'bicep';
					else
						error('bicep channel not read in')
					end

					if contains(app.emg_data.chanlocs(2).labels, 'tricep')
						% correct label
					elseif contains(app.emg_data.chanlocs(2).labels, 'Triceps')
						% incorrect label, change it
						app.emg_data.chanlocs(2).labels = 'tricep';
					else
						error('tricep channel not read in')
					end

					% downsample to 1kHz
					processing_fig_h.Title = 'Down sampling the Data';

					app.emg_data.srate_down = app.samp_rate;
					factor = app.emg_data.srate / app.emg_data.srate_down;
					if abs(factor-fix(factor)) > eps
						fprintf('cocontraction: sampling rate, %g, cannot be downsampled to 1kHz\n', app.emg_data.srate);
						factor = 1;
						app.emg_data.srate_down = app.emg_data.srate;
					else
						fprintf('down sampling by a factor of %d\n', factor)
					end

					app.emg_data.downsamp = zeros(size(app.emg_data.data,1), app.emg_data.pnts/factor);
					for c_cnt = 1:size(app.emg_data.data,1)
						if factor > 1
							app.emg_data.downsamp(c_cnt, :) = decimate(double(app.emg_data.data(c_cnt,:)), factor);
						else
							app.emg_data.downsamp(c_cnt, :) = double(app.emg_data.data(c_cnt, :));
						end
					end
					% add time vector to emg_data. Time of first sample is 1/Fs
					app.emg_data.time = [1:length(app.emg_data.downsamp)] ./ app.emg_data.srate_down; %#ok<NBRAK> % time in seconds.

					% 60 Hz notch filter the data
					processing_fig_h.Title = 'Filtering the Data';
					Fs = app.emg_data.srate_down;
					designed_filter = designfilt('bandstopiir','FilterOrder',10, ...
						'HalfPowerFrequency1',59,'HalfPowerFrequency2',61, ...
						'DesignMethod','butter','SampleRate',Fs);
					app.emg_data.data_60notch = filtfilt(designed_filter, app.emg_data.downsamp')';
					% high pass filter the data (10 Hz)
					app.emg_data.data_hp_filt = hpfilt(app.emg_data.data_60notch, 4, 10, app.emg_data.srate_down);
					% low pass filter the data (400 Hz)
					app.emg_data.data_bp_filt = lpfilt(app.emg_data.data_hp_filt, 4, 400, app.emg_data.srate_down);
					% rectify & low pass filter at 9 Hz for linear envelope
					app.emg_data.linear_envelope = lpfilt(abs(app.emg_data.data_bp_filt), 4, 9, app.emg_data.srate_down);


					% re-initialize resting_emg and coactivation_patches
					if isfield(app.coactivation_patches, 'bicep')
						% delete the patches
						for p_cnt = 1:length(app.coactivation_patches)
							delete(app.coactivation_patches(p_cnt).bicep)
							delete(app.coactivation_patches(p_cnt).tricep)
						end
					end
					app.coactivation_patches = struct();
					app.resting_emg = struct();

					% read in mvc info
					read_mvc_info(app)
					if isempty(app.mvcs)
						uialert(app.CoConUIFigure, 'No MVC files found.', 'Missing files', 'Icon', 'warning' )
						% 						warndlg('No MVC files found.', 'MVC Warning', 'modal')
					end
					% read in resting emg info
					read_resting_emg_info(app)
					if isempty(fieldnames(app.resting_emg))
						uialert(app.CoConUIFigure, 'No Resting EMG files found.', 'Missing files', 'Icon', 'warning' )
						% 						warndlg('No Resting EMG files found.', 'Resting EMG Warning', 'modal')
					end

					% apply resting emg offset and mvc scaling to emg envelopes
					if ~isempty(app.mvcs) && ~isempty(fieldnames(app.resting_emg))
						apply_offest_scaling_to_emg_envelopes(app)
					end

					% compute co-contraction index
					% 					compute_cocontraction(app)

					% 					% fft of the emg data - is there excessive 60 Hz noise?
					% 					doFFT(double(app.emg_data.data(2,:)),app.emg_data.srate)
					% 					% fft of the emg data - is there excessive 60 Hz noise?
					% 					doFFT(double(app.emg_data.data_bp_filt(2,:)),app.emg_data.srate)

					% draw emg and co-contraction data
					emg_analysis_draw_emg_data(app)


					% add event times using latency sample numbers and srate
					for cnt = 1:length(app.emg_data.event)
						app.emg_data.event(cnt).time = app.emg_data.event(cnt).latency/app.emg_data.srate;
						% if this is a bend only or extend only type experiment and the events
						% are 'bend', 'extend', 'rest', change them to 'move', 'relax', 'next'
						% 						if app.ExtendOnlyButton.Value == 1 || app.BendOnlyButton.Value == 1
						% 							switch lower(app.emg_data.event(cnt).code)
						% 								case 'bend'
						% 									app.emg_data.event(cnt).code = 'move';
						% 								case 'extend'
						% 									app.emg_data.event(cnt).code = 'relax';
						% 								case 'rest'
						% 									app.emg_data.event(cnt).code = 'next';
						% 							end
						% 						end
					end

					emg_analysis_draw_events(app) % event lines

					% if isometric experiment, look for previous analysis
					if app.IsometricBicepButton.Value == 1 || app.IsometricTricepButton.Value == 1
						% check for previous analysis info & read in
						app.cocontraction_data = struct(); % empty the struct to clear any old info
						read_cocontraction_info(app)
						if isfield(app.cocontraction_data, 'analysis_by')
							app.AnalysisbyEditField.Value = app.cocontraction_data.analysis_by;
							app.AnalysisdateEditField.Value = app.cocontraction_data.analysis_date;
							%  isometric experiment
							add_isometric_contraction_events_from_file(app)
						end
					end
					close(processing_fig_h) % close processing window
				end % if brain vision file
			end % if valid file & path
		end

		% Button pushed function: ViconSelectButton
		function ViconSelectButtonPushed(app, event)
			[filename, pathname] = uigetfile('*.csv', 'Choose CSV file with Vicon Elbow Angle data');
			if ~isequal(filename,0) &&  ~isequal(pathname,0)
				app.ViconFileNameLabel.Text = fullfile(pathname, filename);

				% show window that something is going on
				processing_fig_h = uiprogressdlg(app.CoConUIFigure, 'Title', 'Loading Vicon Data', 'Indeterminate','on');
				% read in the data
				app.vicon_data = read_vicon_model_trajectories_csv(app.ViconFileNameLabel.Text);

				% compute time vector for vicon
				app.vicon_data.time = [1:height(app.vicon_data.tbl)] ./ app.vicon_data.samp_freq; %#ok<NBRAK>
				% offset vicon time vector by the viconstart event in the vhdr file so
				% emg & angle times are the same
				vicon_offset = 0;
				for cnt = 1:length(app.emg_data.event)
					if contains(lower(app.emg_data.event(cnt).code), 'viconstart')
						vicon_offset = app.emg_data.event(cnt).time;
						break
					end
				end
				app.vicon_data.emg_time = app.vicon_data.time + vicon_offset;

				% lp filter elbow angle at 6 Hz (1 Hz)
				tmp = lpfilt(table2array(app.vicon_data.tbl), 4, 1, app.vicon_data.samp_freq);
				app.vicon_data.lpfilt = array2table(tmp, 'VariableNames', app.vicon_data.tbl.Properties.VariableNames);

				if any(contains(app.vicon_data.lpfilt.Properties.VariableNames, 'ElbAng'))
					% draw the elbow angle data
					emg_analysis_draw_elbow_data(app)
					% elbow velocity
					% is it left or right?
					if any(contains(app.vicon_data.lpfilt.Properties.VariableNames, 'RElb'))
						angle_var = 'RElbAnglesXZY_X_deg';
					end
					if any(contains(app.vicon_data.lpfilt.Properties.VariableNames, 'LElb'))
						angle_var = 'LElbAnglesXZY_X_deg';
					end
					data = table2array(app.vicon_data.lpfilt(:, {angle_var}));
					elb_vel = diff(data) * app.vicon_data.samp_freq;

					% remove existing line, if present
					h_line = findobj(app.UIAxes_elbow_velocity, 'Tag', 'line_elbow_velocity');
					if ~isempty(h_line)
						delete(h_line)
					end
					line(app.UIAxes_elbow_velocity, app.vicon_data.emg_time(1:end-1), elb_vel, 'color', 'r', 'Tag', 'line_elbow_velocity')
					% y = 0 line
					h_line = findobj(app.UIAxes_elbow_velocity, 'Tag', 'line_y_zero');
					if ~isempty(h_line)
						delete(h_line)
					end
					line(app.UIAxes_elbow_velocity, [0 app.vicon_data.emg_time(end)], [0 0], 'color', 'k', 'Tag', 'line_y_zero')

				elseif any(contains(app.vicon_data.lpfilt.Properties.VariableNames, 'MCPAng'))
					% no elbow angle, but there is an MCP Angle
					emg_analysis_draw_mcp_data(app)
				elseif any(contains(app.vicon_data.lpfilt.Properties.VariableNames, 'FIN_X'))
					% don't have elbow angle, only finger marker
					emg_analysis_draw_finger_data(app)
				else
					disp('no Elbow angle or Finger marker to display')
				end
				% check for previous analysis info & read in
				app.cocontraction_data = struct(); % empty the struct to clear any old info
				read_cocontraction_info(app)
				if isfield(app.cocontraction_data, 'analysis_by')
					app.AnalysisbyEditField.Value = app.cocontraction_data.analysis_by;
					app.AnalysisdateEditField.Value = app.cocontraction_data.analysis_date;
					% redraw the cocontraction patches
					% get experiment type
					if app.BendOnlyButton.Value == 1 || app.ExtendOnlyButton.Value == 1 || ...
							app.BendExtendButton.Value == 1
						% movement experiment
						add_isometric_contraction_events_from_file(app) % should rename this function FIXME
					end
				end % if previous cocontraction info
				close(processing_fig_h) % close processing window
			end
		end

		% Menu selected function: AddMovementEndEventMenu
		function AddMovementEndEventMenuSelected(app, event)
			% 			disp('Selected Add movement end event menu')
			% look for the closest move event location to the left of the mouse click location
			closest_event_num = 0;
			closest_event_distance = inf;
			if ~isempty(app.emg_data)
				for e_cnt = 1:length(app.emg_data.event)
					if contains(lower(app.emg_data.event(e_cnt).code), 'move') || ...
							contains(lower(app.emg_data.event(e_cnt).code), 'bend') || ...
							contains(lower(app.emg_data.event(e_cnt).code), 'extend')
						event_distance = app.right_click_loc.x - app.emg_data.event(e_cnt).time;
						if event_distance < closest_event_distance && event_distance >= 0
							% event is to the left of the mouse click
							closest_event_distance = event_distance;
							closest_event_num = e_cnt;
						end
					end
				end
			end
			add_movement_end_event(app, closest_event_num)
			add_coactivation_patch(app, closest_event_num)
		end

		% Button down function: UIAxes_elbow_angle
		function UIAxes_elbow_angleButtonDown(app, event)
			% 			disp('elbow_angle_axes_button_down')
			% 			disp(event)
			if event.Button == 3 % right-click
				% save the position in the axes
				app.right_click_loc.x = event.IntersectionPoint(1);
				app.right_click_loc.y = event.IntersectionPoint(2);
			end
		end

		% Button pushed function: ExportCocontractionDataButton
		function ExportCocontractionDataButtonPushed(app, event)
			% Compute AUC for cocontraction patches
			compute_cocontraction_from_patches(app)
			% update analysis fields
			app.AnalysisdateEditField.Value = datestr(now,'YYYY-mm-DD HH:MM:SS');
			if contains(lower(app.AnalysisbyEditField.Value), 'xxx') || isempty(app.AnalysisbyEditField.Value)
				warndlg('Valid initials must be entered in Analysis by field.', 'Invalid initials warning', 'modal')
				return
			end
			% Save the data
			save_cocontraction_info(app)

		end

		% Button down function: UIAxes_cci
		function UIAxes_cciButtonDown(app, event)
			if event.Button == 3 % right-click
				% save the position in the axes
				app.right_click_loc.x = event.IntersectionPoint(1);
				app.right_click_loc.y = event.IntersectionPoint(2);
			end
		end

		% Menu selected function: AddIsometricPatchMenu
		function AddIsometricPatchMenuSelected(app, event)
			% look for the closest move event location to the left of the mouse click location
			closest_event_num = 0;
			closest_event_distance = inf;
			if ~isempty(app.emg_data)
				for e_cnt = 1:length(app.emg_data.event)
					if contains(lower(app.emg_data.event(e_cnt).code), 'bend') || ...
							contains(lower(app.emg_data.event(e_cnt).code), 'extend')
						event_distance = app.right_click_loc.x - app.emg_data.event(e_cnt).time;
						if event_distance < closest_event_distance && event_distance >= 0
							% event is to the left of the mouse click
							closest_event_distance = event_distance;
							closest_event_num = e_cnt;
						end
					end
				end
			end
			add_isometric_contraction_end_event(app, closest_event_num, app.right_click_loc.x)
			add_coactivation_patch(app, closest_event_num)
		end

		% Button pushed function: apptobaseButton
		function apptobaseButtonPushed(app, event)
			assignin('base','app', app)
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.CoConUIFigure = uifigure('Visible', 'off');
			app.CoConUIFigure.Name = 'Co-contraction Analysis';
			app.UIAxes_bicep = uiaxes(app.CoConUIFigure);
			ylabel(app.UIAxes_bicep, 'Bicep EMG (µV)')
			zlabel(app.UIAxes_bicep, 'Z')
			app.UIAxes_bicep.XTick = [];
			app.UIAxes_tricep = uiaxes(app.CoConUIFigure);
			ylabel(app.UIAxes_tricep, 'Tricep EMG (µV)')
			zlabel(app.UIAxes_tricep, 'Z')
			app.UIAxes_tricep.XTick = [];
			app.UIAxes_cci = uiaxes(app.CoConUIFigure);
			xlabel(app.UIAxes_cci, 'Time (s)')
			ylabel(app.UIAxes_cci, 'EMG Envelopes (%MVC)')
			zlabel(app.UIAxes_cci, 'Z')
			app.UIAxes_cci.ButtonDownFcn = createCallbackFcn(app, @UIAxes_cciButtonDown, true);
			app.UIAxes_elbow_angle = uiaxes(app.CoConUIFigure);
			ylabel(app.UIAxes_elbow_angle, 'Elbow Angle (°)')
			zlabel(app.UIAxes_elbow_angle, 'Z')
			app.UIAxes_elbow_angle.ButtonDownFcn = createCallbackFcn(app, @UIAxes_elbow_angleButtonDown, true);
			app.UIAxes_elbow_velocity = uiaxes(app.CoConUIFigure);
			xlabel(app.UIAxes_elbow_velocity, 'Time (s)')
			ylabel(app.UIAxes_elbow_velocity, 'Elbow Velocity (°/s)')
			zlabel(app.UIAxes_elbow_velocity, 'Z')
			app.EMGSelectButton = uibutton(app.CoConUIFigure, 'push');
			app.EMGSelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
			app.EMGSelectButton.Text = 'Select';
			app.FileNameLabel = uilabel(app.CoConUIFigure);
			app.EditField = uieditfield(app.CoConUIFigure, 'numeric');
			app.EditField_2 = uieditfield(app.CoConUIFigure, 'numeric');
			app.EMGDataFileLabel = uilabel(app.CoConUIFigure);
			app.EMGDataFileLabel.Text = 'EMG Data File:';
			app.cocontractionmlappLabel = uilabel(app.CoConUIFigure);
			app.cocontractionmlappLabel.Text = 'cocontraction.mlapp';
			app.AnalysisbyEditFieldLabel = uilabel(app.CoConUIFigure);
			app.AnalysisbyEditFieldLabel.Text = 'Analysis by:';
			app.AnalysisbyEditField = uieditfield(app.CoConUIFigure, 'text');
			app.AnalysisbyEditField.Value = 'XXX';
			app.AnalysisdateEditFieldLabel = uilabel(app.CoConUIFigure);
			app.AnalysisdateEditFieldLabel.Text = 'Analysis date:';
			app.AnalysisdateEditField = uieditfield(app.CoConUIFigure, 'text');
			app.AnalysisdateEditField.Editable = 'off';
			app.ViconSelectButton = uibutton(app.CoConUIFigure, 'push');
			app.ViconSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ViconSelectButtonPushed, true);
			app.ViconSelectButton.Text = 'Select';
			app.ViconFileNameLabel = uilabel(app.CoConUIFigure);
			app.ViconDataFileLabel = uilabel(app.CoConUIFigure);
			app.ViconDataFileLabel.Text = 'Vicon CSV File:';
			app.ExportCocontractionDataButton = uibutton(app.CoConUIFigure, 'push');
			app.ExportCocontractionDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCocontractionDataButtonPushed, true);
			app.ExportCocontractionDataButton.Text = 'Export Cocontraction Data';
			app.ExperimentButtonGroup = uibuttongroup(app.CoConUIFigure);
			app.ExperimentButtonGroup.Title = 'Experiment:';
			app.BendOnlyButton = uiradiobutton(app.ExperimentButtonGroup);
			app.BendOnlyButton.Text = 'Bend Only';
			app.BendOnlyButton.Value = true;
			app.ExtendOnlyButton = uiradiobutton(app.ExperimentButtonGroup);
			app.ExtendOnlyButton.Text = 'Extend Only';
			app.BendExtendButton = uiradiobutton(app.ExperimentButtonGroup);
			app.BendExtendButton.Text = 'Bend-Extend';
			app.IsometricBicepButton = uiradiobutton(app.ExperimentButtonGroup);
			app.IsometricBicepButton.Text = 'Isometric Bicep';
			app.IsometricTricepButton = uiradiobutton(app.ExperimentButtonGroup);
			app.IsometricTricepButton.Text = 'Isometric Tricep';
			app.FingerExtendButton = uiradiobutton(app.ExperimentButtonGroup);
			app.FingerExtendButton.Text = 'Finger Extend';
			app.apptobaseButton = uibutton(app.CoConUIFigure, 'push');
			app.apptobaseButton.ButtonPushedFcn = createCallbackFcn(app, @apptobaseButtonPushed, true);
			app.apptobaseButton.Text = 'app to base';
			app.AddEventContextMenu = uicontextmenu(app.CoConUIFigure);
			app.AddMovementEndEventMenu = uimenu(app.AddEventContextMenu);
			app.AddMovementEndEventMenu.MenuSelectedFcn = createCallbackFcn(app, @AddMovementEndEventMenuSelected, true);
			app.AddMovementEndEventMenu.Text = 'Add Movement End Event';
			% Assign app.AddEventContextMenu
			app.UIAxes_elbow_angle.ContextMenu = app.AddEventContextMenu;
			app.AddIsometricContextMenu = uicontextmenu(app.CoConUIFigure);
			app.AddIsometricPatchMenu = uimenu(app.AddIsometricContextMenu);
			app.AddIsometricPatchMenu.MenuSelectedFcn = createCallbackFcn(app, @AddIsometricPatchMenuSelected, true);
			app.AddIsometricPatchMenu.Text = 'Add Isometric Patch';
			% Assign app.AddIsometricContextMenu
			app.UIAxes_cci.ContextMenu = app.AddIsometricContextMenu;
			% Show the figure after all components are created
			app.CoConUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = cocontraction

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.CoConUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.CoConUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CoConUIFigure)
		end
	end
end