classdef mvc < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		MVCAnalysisUIFigure             matlab.ui.Figure
		AnalysisdateEditField           matlab.ui.control.EditField
		AnalysisdateEditFieldLabel      matlab.ui.control.Label
		AnalysisbyEditField             matlab.ui.control.EditField
		AnalysisbyEditFieldLabel        matlab.ui.control.Label
		mvcmlappversion0720221128Label  matlab.ui.control.Label
		SaveMVCsButton                  matlab.ui.control.Button
		FileNameLabel                   matlab.ui.control.Label
		MVCfileLabel                    matlab.ui.control.Label
		SelectButton                    matlab.ui.control.Button
		UIAxes_tricep                   matlab.ui.control.UIAxes
		UIAxes_bicep                    matlab.ui.control.UIAxes
		UIAxes_fingerextensors          matlab.ui.control.UIAxes
		UIAxes_fingerflexors            matlab.ui.control.UIAxes
		ContextMenu                     matlab.ui.container.ContextMenu
		AddRestEMGPatchMenu             matlab.ui.container.Menu
		AddMVCPatchMenu                 matlab.ui.container.Menu
	end


	properties (Access = private)

	end

	properties (Access = public)
		emg_data	 % emg data read in from BrainVision file
		mvcs			% struct with mvc values
		resting_emg		% struct with resting emg values

		right_click_loc % mouse location of right-click in axes
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% 			keyboard
		end

		% Button pushed function: SelectButton
		function SelectButtonPushed(app, event)
			[filename, pathname] = uigetfile('*.vhdr', 'Choose vhdr file with MVC data');
			if ~isequal(filename,0) &&  ~isequal(pathname,0)
				app.FileNameLabel.Text = fullfile(pathname, filename);
				% read in the data
				if contains(filename, '.vhdr')
					processing_fig_h = uiprogressdlg(app.MVCAnalysisUIFigure, 'Title', 'Loading EMG Data', 'Indeterminate','on');

					% depending upon the date of the file, define the 2 or 4 emg channels
					% files created after 2022-11-08 have 4 muscles instead of 2
					file_info = dir(app.FileNameLabel.Text);
					if file_info.datenum > datenum(2022,11,08) %#ok<DATNM> 
						emg_chans = 64:67;
					else
						emg_chans = [64 65];
					end
					% read in data from brain vision file
					try
						app.emg_data = pop_loadbv(pathname, filename, [], emg_chans);
					catch ME
						if strcmpi(ME.message, 'chans out of available channel range' )
							% try again with fewer channels
							if length(emg_chans) == 4
								emg_chans = [64 65];
							end
							try
								app.emg_data = pop_loadbv(pathname, filename, [], emg_chans);
							catch ME2
								if strcmpi(ME2.message, 'chans out of available channel range' )
									disp('Tried to reduce from 4 to 2 EMG channels. Still not reading in data from ')
									disp(fullfile(pathname, filename))
								else
									rethrow(ME2)
								end
							end
						else
							rethrow(ME)
						end
					end
					% data structure:
					%   comments: 'Original file: 20120517_inv_wrist_flexors_active_rc_epochs_450ms.seg' (or .eeg)
					%       srate: 5000				sampling rate in Hz
					%      nbchan: 8				number of channels recorded
					%    chanlocs: [1x8 struct]		array of the channel names (strings)
					%        pnts: 2250				number of data points in a trial
					%        data: [8x90000 single]	data matrix (1 row for each data collection channel)
					%      trials: 40				number of trials
					%        xmin: -0.0500
					%        xmax: 17.9998
					%       event: [1x80 struct]	struct array with fields:
					%									latency			starting data point number for the event
					%									type			'R128' events are the start of trials we want
					%									code
					%									duration
					%									urevent
					%									epoch
					%     urevent: [1x120 struct]
					%         ref: 'common'

					% verify that the 2 data channels are bicep and tricep
					if contains(app.emg_data.chanlocs(1).labels, 'bicep')
						% correct label
					elseif contains(app.emg_data.chanlocs(1).labels, 'Biceps')
						% incorrect label, change it
						app.emg_data.chanlocs(1).labels = 'bicep';
					else
						error('bicep channel not read in')
					end

					if contains(app.emg_data.chanlocs(2).labels, 'tricep')
						% correct label
					elseif contains(app.emg_data.chanlocs(2).labels, 'Triceps')
						% incorrect label, change it
						app.emg_data.chanlocs(2).labels = 'tricep';
					else
						error('tricep channel not read in')
					end
					% if later data with finger muscles
					if length(app.emg_data.chanlocs) > 2

					end

					% add time vector to emg_data. Time of first sample is 1/Fs
					app.emg_data.time = [1:app.emg_data.pnts] ./ app.emg_data.srate; %#ok<NBRAK> % time in seconds.
					% add event times using latency sample numbers and srate
					for i = 1:length(app.emg_data.event)
						app.emg_data.event(i).time = app.emg_data.event(i).latency/app.emg_data.srate;
					end
					% 60 Hz notch filter the data
					Fs = app.emg_data.srate;
					designed_filter = designfilt('bandstopiir','FilterOrder',10, ...
						'HalfPowerFrequency1',59,'HalfPowerFrequency2',61, ...
						'DesignMethod','butter','SampleRate',Fs);
					app.emg_data.data_60notch = filtfilt(designed_filter, double(app.emg_data.data)')';
					% 					figure
					% 					subplot(2,1,1)
					% 					plot(app.emg_data.time, app.emg_data.data(1,:))
					% 					hold on
					% 					plot(app.emg_data.time, app.emg_data.data_60notch(1,:))
					% 					subplot(2,1,2)
					% 					plot(app.emg_data.time, app.emg_data.data(2,:))
					% 					hold on
					% 					plot(app.emg_data.time, app.emg_data.data_60notch(2,:))

					% high pass filter the data (10 Hz)
					app.emg_data.data_hp_filt = hpfilt(app.emg_data.data_60notch, 4, 10, app.emg_data.srate);
					% low pass filter the data (400 Hz)
					app.emg_data.data_bp_filt = lpfilt(app.emg_data.data_hp_filt, 4, 400, app.emg_data.srate);
					% rectify & low pass filter at 9 Hz for linear envelope
					app.emg_data.linear_envelope = lpfilt(abs(app.emg_data.data_bp_filt), 4, 9, app.emg_data.srate);

					emg_analysis_draw_emg_data(app)

					% read in previous mvc info, if it exists
					app.mvcs = struct();	% make sure we don't have old data from the previous emg data file
					read_mvc_info(app)
					f_names = fieldnames(app.mvcs);
					if ~isempty(f_names)
						app.AnalysisbyEditField.Value = app.mvcs.(f_names{1}).analysis_by;
						app.AnalysisdateEditField.Value = app.mvcs.(f_names{1}).analysis_date;
					end
					emg_analysis_draw_mvc_patches(app) % patches for mvc

					% read in previous resting emg info, if it exists
					app.resting_emg = struct(); % remove any old data
					read_resting_emg_info(app)
					emg_analysis_draw_rest_emg_patch(app) % patch for lowest/resting emg
				end
				close(processing_fig_h) % close processing window
			end
		end

		% Button pushed function: SaveMVCsButton
		function SaveMVCsButtonPushed(app, event)
			% get RMS value in mvc patches
			compute_mvcs(app)
			% save to file
			app.AnalysisdateEditField.Value = datestr(now,'YYYY-mm-DD HH:MM:SS'); %#ok<TNOW1,DATST>
			if contains(lower(app.AnalysisbyEditField.Value), 'xxx') || isempty(app.AnalysisbyEditField.Value)
				warndlg('Valid initials must be entered in Analysis by field.', 'Invalid initials warning', 'modal')
				return
			end
			save_mvcs(app)

			% same thing for resting_emg patches
			compute_resting_emg(app)
			save_resting_emg(app)
		end

		% Menu selected function: AddMVCPatchMenu, AddRestEMGPatchMenu
		function AddEMGPatchMenuSelected(app, event)
			if ~isempty(app.emg_data)
				disp(app.right_click_loc.x)
				% look in 10 s window around mouse click for the 1 s window of minimal
				% emg
				h_line = findobj(event.Source.Parent.Parent.CurrentAxes, '-regexp', 'Tag', '.*envelope');
				assert(~isempty(h_line), ['Did not find emg line with tag line_<muscle>_envelope in axes with tag ' ...
					event.Source.Parent.Parent.CurrentAxes.Tag])

				emg_data.xdata = h_line.XData; %#ok<ADPROPLC>
				emg_data.ydata = h_line.YData; %#ok<ADPROPLC>
				if contains(event.Source.Text, 'MVC')
					[beg_t, end_t] = emg_analysis_find_max_emg(app, emg_data, app.right_click_loc.x-5, ...
						app.right_click_loc.x+5, 1); %#ok<ADPROPLC> % 1 sec interval to find the max
					patch_tag = 'mvc';
				elseif contains(event.Source.Text, 'Rest')
					[beg_t, end_t] = emg_analysis_find_min_emg(app, emg_data, app.right_click_loc.x-5, ...
						app.right_click_loc.x+5, 1); %#ok<ADPROPLC> % 1 sec interval to find the min
					patch_tag = 'resting';
				end


				emg_analysis_add_patch(event.Source.Parent.Parent.CurrentAxes, beg_t, end_t, patch_tag)
			end
		end

		% Button down function: UIAxes_bicep, UIAxes_fingerextensors, 
		% ...and 2 other components
		function UIAxes_ButtonDown(app, event)
			if event.Button == 3 % right-click
				% save the position in the axes
				app.right_click_loc.x = event.IntersectionPoint(1);
				app.right_click_loc.y = event.IntersectionPoint(2);
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.MVCAnalysisUIFigure = uifigure('Visible', 'off');
			app.MVCAnalysisUIFigure.Name = 'MVC Analysis';
			app.UIAxes_fingerflexors = uiaxes(app.MVCAnalysisUIFigure);
			ylabel(app.UIAxes_fingerflexors, 'FingerFlexors')
			zlabel(app.UIAxes_fingerflexors, 'Z')
			app.UIAxes_fingerflexors.ButtonDownFcn = createCallbackFcn(app, @UIAxes_ButtonDown, true);
			app.UIAxes_fingerflexors.Tag = 'axes_bicep';
			app.UIAxes_fingerextensors = uiaxes(app.MVCAnalysisUIFigure);
			xlabel(app.UIAxes_fingerextensors, 'Time (s)')
			ylabel(app.UIAxes_fingerextensors, 'FingerExtensors')
			zlabel(app.UIAxes_fingerextensors, 'Z')
			app.UIAxes_fingerextensors.ButtonDownFcn = createCallbackFcn(app, @UIAxes_ButtonDown, true);
			app.UIAxes_fingerextensors.Tag = 'axes_tricep';
			app.UIAxes_bicep = uiaxes(app.MVCAnalysisUIFigure);
			ylabel(app.UIAxes_bicep, 'Bicep')
			zlabel(app.UIAxes_bicep, 'Z')
			app.UIAxes_bicep.ButtonDownFcn = createCallbackFcn(app, @UIAxes_ButtonDown, true);
			app.UIAxes_bicep.Tag = 'axes_bicep';
			app.UIAxes_tricep = uiaxes(app.MVCAnalysisUIFigure);
			ylabel(app.UIAxes_tricep, 'Tricep')
			zlabel(app.UIAxes_tricep, 'Z')
			app.UIAxes_tricep.ButtonDownFcn = createCallbackFcn(app, @UIAxes_ButtonDown, true);
			app.UIAxes_tricep.Tag = 'axes_bicep';
			app.SelectButton = uibutton(app.MVCAnalysisUIFigure, 'push');
			app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
			app.SelectButton.Text = 'Select';
			app.MVCfileLabel = uilabel(app.MVCAnalysisUIFigure);
			app.MVCfileLabel.Text = 'MVC file:';
			app.FileNameLabel = uilabel(app.MVCAnalysisUIFigure);
			app.SaveMVCsButton = uibutton(app.MVCAnalysisUIFigure, 'push');
			app.SaveMVCsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMVCsButtonPushed, true);
			app.SaveMVCsButton.Text = 'Save MVCs';
			app.mvcmlappversion0720221128Label = uilabel(app.MVCAnalysisUIFigure);
			app.mvcmlappversion0720221128Label.Text = 'mvc.mlapp version 0.7 2022-11-28';
			app.AnalysisbyEditFieldLabel = uilabel(app.MVCAnalysisUIFigure);
			app.AnalysisbyEditFieldLabel.Text = 'Analysis by:';
			app.AnalysisbyEditField = uieditfield(app.MVCAnalysisUIFigure, 'text');
			app.AnalysisbyEditField.Value = 'XXX';
			app.AnalysisdateEditFieldLabel = uilabel(app.MVCAnalysisUIFigure);
			app.AnalysisdateEditFieldLabel.Text = 'Analysis date:';
			app.AnalysisdateEditField = uieditfield(app.MVCAnalysisUIFigure, 'text');
			app.AnalysisdateEditField.Editable = 'off';
			app.AnalysisdateEditField.Value = 'XXXX-XX-XX';
			app.ContextMenu = uicontextmenu(app.MVCAnalysisUIFigure);
			app.AddRestEMGPatchMenu = uimenu(app.ContextMenu);
			app.AddRestEMGPatchMenu.MenuSelectedFcn = createCallbackFcn(app, @AddEMGPatchMenuSelected, true);
			app.AddRestEMGPatchMenu.Text = 'Add Rest EMG Patch';
			app.AddMVCPatchMenu = uimenu(app.ContextMenu);
			app.AddMVCPatchMenu.MenuSelectedFcn = createCallbackFcn(app, @AddEMGPatchMenuSelected, true);
			app.AddMVCPatchMenu.Text = 'Add MVC Patch';
			% Assign app.ContextMenu
			app.UIAxes_fingerflexors.ContextMenu = app.ContextMenu;
			app.UIAxes_fingerextensors.ContextMenu = app.ContextMenu;
			app.UIAxes_bicep.ContextMenu = app.ContextMenu;
			app.UIAxes_tricep.ContextMenu = app.ContextMenu;
			% Show the figure after all components are created
			app.MVCAnalysisUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = mvc

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.MVCAnalysisUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.MVCAnalysisUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.MVCAnalysisUIFigure)
		end
	end
end